classdef MainFrame_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        File                     matlab.ui.container.Menu
        About                    matlab.ui.container.Menu
        Exist                    matlab.ui.container.Menu
        uipanel7                 matlab.ui.container.Panel
        edit14                   matlab.ui.control.TextArea
        uipanel4                 matlab.ui.container.Panel
        edit11                   matlab.ui.control.TextArea
        pushbuttonExit           matlab.ui.control.Button
        uipanel3                 matlab.ui.container.ButtonGroup
        editVideoFormat          matlab.ui.control.EditField
        text8                    matlab.ui.control.Label
        editDuration             matlab.ui.control.EditField
        text7                    matlab.ui.control.Label
        editVideoFilePath        matlab.ui.control.EditField
        text6                    matlab.ui.control.Label
        editFrameRate            matlab.ui.control.EditField
        text5                    matlab.ui.control.Label
        editFrameHeight          matlab.ui.control.EditField
        text4                    matlab.ui.control.Label
        editFrameWidth           matlab.ui.control.EditField
        text3                    matlab.ui.control.Label
        editFrameNum             matlab.ui.control.EditField
        text2                    matlab.ui.control.Label
        uipanel2                 matlab.ui.container.ButtonGroup
        pushbuttonGetVideoInfo   matlab.ui.control.Button
        pushbuttonImageList      matlab.ui.control.Button
        pushbuttonOpenVideoFile  matlab.ui.control.Button
        uipanel1                 matlab.ui.container.Panel
        editSlider               matlab.ui.control.EditField
        sliderVideoPlay          matlab.ui.control.Slider
        pushbuttonSnap           matlab.ui.control.Button
        pushbuttonStop           matlab.ui.control.Button
        pushbuttonPause          matlab.ui.control.Button
        pushbuttonPlay           matlab.ui.control.Button
        axesVideo                matlab.ui.control.UIAxes
        text1                    matlab.ui.control.Label
    end

    
    methods (Access = private)
        function editInfo_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to editInfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit_videowidth_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit_videowidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbuttonStopCheck_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbuttonStopCheck.
            
            % hObject    handle to pushbuttonStopCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ÊÓÆµ·ÖÎö
            [s, sx, sy] =  MotionAnalysis();
            handles.s = s;
            handles.sx = sx;
            handles.sy = sy;
            % Update handles structure
            guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MainFrame_OpeningFcn(app, varargin)
            % --- Executes just before MainFrame is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MainFrame (see VARARGIN)
            
            % Choose default command line output for MainFrame
            handles.output = hObject;
            clc;
            handles.videoFilePath = 0;
            handles.videoInfo = 0;
            handles.videoImgList = 0;
            handles.videoStop = 1;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: About
        function About_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to About (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = 'Video Filtering';
            msgbox(str, 'Inbox');
        end

        % Menu selected function: Exist
        function Exist_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Exist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ÍË³ö²Ëµ¥
            choice = questdlg('Confirm to Log out?', ...
                'Logout', ...
                'Confirm','Cancel','Cancel');
            switch choice
                case 'Confirm'
                    close;
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: pushbuttonExit
        function pushbuttonExit_Callback(app, event)
            % --- Executes on button press in pushbuttonExit.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonExit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ÍË³öÏµÍ³°´Å¥
            choice = questdlg('Confirm to Log out?', ...
                'Logout', ...
                'Confirm','Cancel','Cancel');
            switch choice
                case 'Confirm'
                    close;
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: pushbuttonGetVideoInfo
        function pushbuttonGetVideoInfo_Callback(app, event)
            % --- Executes on button press in pushbuttonGetVideoInfo.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonGetVideoInfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % »ñÈ¡ÊÓÆµÐÅÏ¢°´Å¥
            if handles.videoFilePath == 0
                msgbox('Please Load Video Info First', 'Inbox');
                return;
            end
            % »ñÈ¡ÐÅÏ¢²¢±£´æ
            videoInfo = VideoReader(handles.videoFilePath);
            handles.videoInfo = videoInfo;
            guidata(hObject, handles);
            % ÌáÈ¡ÐÅÏ¢²¢ÏÔÊ¾µ½½çÃæ
            set(handles.editFrameNum, 'String', sprintf('%d', videoInfo.NumberOfFrames));
            set(handles.editFrameWidth, 'String', sprintf('%d px', videoInfo.Width));
            set(handles.editFrameHeight, 'String', sprintf('%d px', videoInfo.Height));
            set(handles.editFrameRate, 'String', sprintf('%d f/s', videoInfo.FrameRate));
            set(handles.editDuration, 'String', sprintf('%.1f s', videoInfo.Duration));
            set(handles.editVideoFormat, 'String', sprintf('%s', videoInfo.VideoFormat));
            msgbox('Get video info successful', 'Inbox');
        end

        % Button pushed function: pushbuttonImageList
        function pushbuttonImageList_Callback(app, event)
            % --- Executes on button press in pushbuttonImageList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonImageList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % »ñÈ¡Í¼ÏñÐòÁÐ°´Å¥
            if handles.videoInfo == 0
                msgbox('Please Get Video Info First', 'Inbox');
                return;
            end
            Video2Images(handles.videoFilePath);
            msgbox('Get Frames Successful£¡', 'Inbox');
        end

        % Button pushed function: pushbuttonOpenVideoFile
        function pushbuttonOpenVideoFile_Callback(app, event)
            % --- Executes on button press in pushbuttonOpenVideoFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonOpenVideoFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ´ò¿ªÊÓÆµÎÄ¼þ°´Å¥
            videoFilePath = OpenVideoFile();
            if videoFilePath == 0
                return;
            end
            set(handles.editVideoFilePath, 'String', videoFilePath);
            msgbox('Load Video Successful£¡', 'Inbox');
            handles.videoFilePath = videoFilePath;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbuttonPause
        function pushbuttonPause_Callback(app, event)
            % --- Executes on button press in pushbuttonPause.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPause (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ÔÝÍ£°´Å¥
            % »ñÈ¡ÏìÓ¦±ê¼Ç
            str = get(handles.pushbuttonPause, 'tag');
            if strcmp(str, 'pushbuttonPause') == 1
                set(handles.pushbuttonPause, 'tag', 'pushbuttonContinue', 'String', 'Continue');
                pause on;
            else
                set(handles.pushbuttonPause, 'tag', 'pushbuttonPause', 'String', 'Pause');
                pause off;
            end
        end

        % Button pushed function: pushbuttonPlay
        function pushbuttonPlay_Callback(app, event)
            % --- Executes on button press in pushbuttonPlay.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ²¥·Å°´Å¥
            set(handles.pushbuttonPause, 'Enable', 'On');
            set(handles.pushbuttonPause, 'tag', 'pushbuttonPause', 'String', 'Pause');
            set(handles.sliderVideoPlay, 'Max', handles.videoInfo.NumberOfFrames, 'Min', 0, 'Value', 1);
            set(handles.editSlider, 'String', sprintf('%d/%d', 0, handles.videoInfo.NumberOfFrames));
            % Ñ­»·ÔØÈëÊÓÆµÖ¡Í¼Ïñ²¢ÏÔÊ¾
            for i = 1 : handles.videoInfo.NumberOfFrames
                waitfor(handles.pushbuttonPause,'tag','pushbuttonPause');
                I = imread(fullfile(pwd, sprintf('video_images\\%03d.jpg', i)));
                try
                    imshow(I, [], 'Parent', handles.axesVideo);
                    % ÉèÖÃ½ø¶ÈÌõ
                    set(handles.sliderVideoPlay, 'Value', i);
                    set(handles.editSlider, 'String', sprintf('%d/%d', i, handles.videoInfo.NumberOfFrames));
                catch
                    return;
                end
                drawnow;
            end
            % ¿ØÖÆÔÝÍ£°´Å¥
            set(handles.pushbuttonPause, 'Enable', 'Off');
        end

        % Button pushed function: pushbuttonSnap
        function pushbuttonSnap_Callback(app, event)
            % --- Executes on button press in pushbuttonSnap.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSnap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ×¥Í¼°´Å¥ÏìÓ¦º¯Êý
            SnapImage();
        end

        % Button pushed function: pushbuttonStop
        function pushbuttonStop_Callback(app, event)
            % --- Executes on button press in pushbuttonStop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonStop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Í£Ö¹°´Å¥
            axes(handles.axesVideo); cla; axis on; box on;
            set(gca, 'XTick', [], 'YTick', [], ...
                'XTickLabel', '', 'YTickLabel', '', 'Color', [0.7020 0.7804 1.0000]);
            set(handles.editSlider, 'String', '0/0');
            set(handles.sliderVideoPlay, 'Value', 0);
            set(handles.pushbuttonPause, 'tag', 'pushbuttonContinue', 'String', 'Continue');
            set(handles.pushbuttonPause, 'Enable', 'Off');
            set(handles.pushbuttonPause, 'String', 'Pause');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = '视频处理系统';
            app.figure1.Tag = 'figure1';
            app.File = uimenu(app.figure1);
            app.File.Text = '文件';
            app.File.Tag = 'File';
            app.About = uimenu(app.File);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @About_Callback, true);
            app.About.Text = '关于';
            app.About.Tag = 'About';
            app.Exist = uimenu(app.File);
            app.Exist.MenuSelectedFcn = createCallbackFcn(app, @Exist_Callback, true);
            app.Exist.Text = '退出';
            app.Exist.Tag = 'Exist';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Video Filtering';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Video Display';
            app.uipanel1.Tag = 'uipanel1';
            app.axesVideo = uiaxes(app.uipanel1);
            app.axesVideo.XTick = zeros(1,0);
            app.axesVideo.XTickLabel = {''; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.axesVideo.YTick = zeros(1,0);
            app.axesVideo.YTickLabel = {''; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.axesVideo.Tag = 'axesVideo';
            app.pushbuttonPlay = uibutton(app.uipanel1, 'push');
            app.pushbuttonPlay.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlay_Callback, true);
            app.pushbuttonPlay.Tag = 'pushbuttonPlay';
            app.pushbuttonPlay.Text = 'Play';
            app.pushbuttonPause = uibutton(app.uipanel1, 'push');
            app.pushbuttonPause.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPause_Callback, true);
            app.pushbuttonPause.Tag = 'pushbuttonPause';
            app.pushbuttonPause.Enable = 'off';
            app.pushbuttonPause.Text = 'Pause';
            app.pushbuttonStop = uibutton(app.uipanel1, 'push');
            app.pushbuttonStop.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStop_Callback, true);
            app.pushbuttonStop.Tag = 'pushbuttonStop';
            app.pushbuttonStop.Text = 'Stop';
            app.pushbuttonSnap = uibutton(app.uipanel1, 'push');
            app.pushbuttonSnap.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSnap_Callback, true);
            app.pushbuttonSnap.Tag = 'pushbuttonSnap';
            app.pushbuttonSnap.Text = 'Snapshot';
            app.sliderVideoPlay = uislider(app.uipanel1);
            app.sliderVideoPlay.Limits = [0 1];
            app.sliderVideoPlay.MajorTicks = [];
            app.sliderVideoPlay.MinorTicks = [];
            app.sliderVideoPlay.Tag = 'sliderVideoPlay';
            app.editSlider = uieditfield(app.uipanel1, 'text');
            app.editSlider.Tag = 'editSlider';
            app.editSlider.Value = '0/0';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Title = 'Control Panel';
            app.uipanel2.Tag = 'uipanel2';
            app.pushbuttonOpenVideoFile = uibutton(app.uipanel2, 'push');
            app.pushbuttonOpenVideoFile.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonOpenVideoFile_Callback, true);
            app.pushbuttonOpenVideoFile.Tag = 'pushbuttonOpenVideoFile';
            app.pushbuttonOpenVideoFile.Text = 'Open Video File';
            app.pushbuttonImageList = uibutton(app.uipanel2, 'push');
            app.pushbuttonImageList.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonImageList_Callback, true);
            app.pushbuttonImageList.Tag = 'pushbuttonImageList';
            app.pushbuttonImageList.Text = 'Get Frames';
            app.pushbuttonGetVideoInfo = uibutton(app.uipanel2, 'push');
            app.pushbuttonGetVideoInfo.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonGetVideoInfo_Callback, true);
            app.pushbuttonGetVideoInfo.Tag = 'pushbuttonGetVideoInfo';
            app.pushbuttonGetVideoInfo.Text = 'Get Video Info';
            app.uipanel3 = uibuttongroup(app.figure1);
            app.uipanel3.Title = 'Video Info';
            app.uipanel3.Tag = 'uipanel3';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Num Frames';
            app.editFrameNum = uieditfield(app.uipanel3, 'text');
            app.editFrameNum.Tag = 'editFrameNum';
            app.editFrameNum.Value = 'Unknown';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Width';
            app.editFrameWidth = uieditfield(app.uipanel3, 'text');
            app.editFrameWidth.Tag = 'editFrameWidth';
            app.editFrameWidth.Value = 'Unknown';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Height';
            app.editFrameHeight = uieditfield(app.uipanel3, 'text');
            app.editFrameHeight.Tag = 'editFrameHeight';
            app.editFrameHeight.Value = 'Unknown';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'FR';
            app.editFrameRate = uieditfield(app.uipanel3, 'text');
            app.editFrameRate.Tag = 'editFrameRate';
            app.editFrameRate.Value = 'Unknown';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Path';
            app.editVideoFilePath = uieditfield(app.uipanel3, 'text');
            app.editVideoFilePath.Tag = 'editVideoFilePath';
            app.editVideoFilePath.Value = 'Unknown';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Length';
            app.editDuration = uieditfield(app.uipanel3, 'text');
            app.editDuration.Tag = 'editDuration';
            app.editDuration.Value = 'Unknown';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Format';
            app.editVideoFormat = uieditfield(app.uipanel3, 'text');
            app.editVideoFormat.Tag = 'editVideoFormat';
            app.editVideoFormat.Value = 'Unknown';
            app.pushbuttonExit = uibutton(app.figure1, 'push');
            app.pushbuttonExit.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExit_Callback, true);
            app.pushbuttonExit.Tag = 'pushbuttonExit';
            app.pushbuttonExit.Text = 'Log Out';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Usere Guide';
            app.uipanel4.Tag = 'uipanel4';
            app.edit11 = uitextarea(app.uipanel4);
            app.edit11.Tag = 'edit11';
            app.edit11.Enable = 'off';
            app.edit11.Value = {'Load Video File'; 'Get Video Info'; 'Get Frames'; 'Choose Filter to apply'; 'Play Filtered Video'};
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Discription';
            app.uipanel7.Tag = 'uipanel7';
            app.edit14 = uitextarea(app.uipanel7);
            app.edit14.Tag = 'edit14';
            app.edit14.Enable = 'off';
            app.edit14.Value = {'GUI for video processing. Allows the loading, getting information, applying filters, saving on videos. The filter includes snow, stretch, cartoon, watercolor.'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainFrame_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MainFrame_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end